classdef Odor_System < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        FileOperationPanel        matlab.ui.container.Panel
        BrowseButton              matlab.ui.control.Button
        TextArea                  matlab.ui.control.TextArea
        BrowseButton_2            matlab.ui.control.Button
        TextArea_2                matlab.ui.control.TextArea
        FileSaveLabel             matlab.ui.control.Label
        TimeTableLabel            matlab.ui.control.Label
        SerialDataMonitorPanel    matlab.ui.container.Panel
        DataTextAreaLabel         matlab.ui.control.Label
        DataTextArea              matlab.ui.control.TextArea
        ControlButtonPanel        matlab.ui.container.Panel
        StartButton               matlab.ui.control.Button
        StopButton                matlab.ui.control.Button
        ClearButton               matlab.ui.control.Button
        ValveControllerPanel      matlab.ui.container.Panel
        SendButton                matlab.ui.control.Button
        CH1TextAreaLabel          matlab.ui.control.Label
        CH1TextArea               matlab.ui.control.TextArea
        CH2TextAreaLabel          matlab.ui.control.Label
        CH2TextArea               matlab.ui.control.TextArea
        CH3TextAreaLabel          matlab.ui.control.Label
        CH3TextArea               matlab.ui.control.TextArea
        CH4TextAreaLabel          matlab.ui.control.Label
        CH4TextArea               matlab.ui.control.TextArea
        CH5TextAreaLabel          matlab.ui.control.Label
        CH5TextArea               matlab.ui.control.TextArea
        CH6TextAreaLabel          matlab.ui.control.Label
        CH6TextArea               matlab.ui.control.TextArea
        CH7TextAreaLabel          matlab.ui.control.Label
        CH7TextArea               matlab.ui.control.TextArea
        CH8TextAreaLabel          matlab.ui.control.Label
        CH8TextArea               matlab.ui.control.TextArea
        CurveMonitorPanel         matlab.ui.container.Panel
        TabGroup                  matlab.ui.container.TabGroup
        Tab1                      matlab.ui.container.Tab
        UIAxes_1                  matlab.ui.control.UIAxes
        Switch_1                  matlab.ui.control.Switch
        CH2Tab                    matlab.ui.container.Tab
        UIAxes_2                  matlab.ui.control.UIAxes
        Switch_2                  matlab.ui.control.Switch
        CH3Tab                    matlab.ui.container.Tab
        UIAxes_3                  matlab.ui.control.UIAxes
        Switch_3                  matlab.ui.control.Switch
        CH4Tab                    matlab.ui.container.Tab
        UIAxes_4                  matlab.ui.control.UIAxes
        Switch_4                  matlab.ui.control.Switch
        CH5Tab                    matlab.ui.container.Tab
        UIAxes_5                  matlab.ui.control.UIAxes
        Switch_5                  matlab.ui.control.Switch
        CH6Tab                    matlab.ui.container.Tab
        Switch_6                  matlab.ui.control.Switch
        UIAxes_6                  matlab.ui.control.UIAxes
        CH7Tab                    matlab.ui.container.Tab
        UIAxes_7                  matlab.ui.control.UIAxes
        Switch_7                  matlab.ui.control.Switch
        CH8Tab                    matlab.ui.container.Tab
        UIAxes_8                  matlab.ui.control.UIAxes
        Switch_8                  matlab.ui.control.Switch
        TotalTab                  matlab.ui.container.Tab
        UIAxes_9                  matlab.ui.control.UIAxes
        Switch_9                  matlab.ui.control.Switch
        TimerPanel                matlab.ui.container.Panel
        TimeTextArea              matlab.ui.control.TextArea
        OptionsPanel              matlab.ui.container.Panel
        AutoButton                matlab.ui.control.Button
        Lamp                      matlab.ui.control.Lamp
        ManualButton              matlab.ui.control.Button
        Lamp_2                    matlab.ui.control.Lamp
        ConnectionPanel           matlab.ui.container.Panel
        ConnectButton             matlab.ui.control.Button
        SerialPortDropDownLabel   matlab.ui.control.Label
        SerialPortDropDown        matlab.ui.control.DropDown
        Lamp_3                    matlab.ui.control.Lamp
        StatusIndicatorPanel      matlab.ui.container.Panel
        Lamp_4                    matlab.ui.control.Lamp
        Lamp_5                    matlab.ui.control.Lamp
        Lamp_6                    matlab.ui.control.Lamp
        PreparingLabel            matlab.ui.control.Label
        ProcessingLabel           matlab.ui.control.Label
        ClearingLabel             matlab.ui.control.Label
        HumidityTemperaturePanel  matlab.ui.container.Panel
        EditField                 matlab.ui.control.EditField
        EditField_2               matlab.ui.control.EditField
        Label                     matlab.ui.control.Label
        Label_2                   matlab.ui.control.Label
    end

    
    properties (Access = private)
        obj % Serial Port
        a % enable variable for continuous data saving
        b % enable variable for interrupt signal waiting
        save % data array
        time % counting
        f % file name and address
        pwm_data % output data
        fid %file
        
        enable_fig_1 % enable value for plot
        enable_fig_2
        enable_fig_3
        enable_fig_4
        enable_fig_5
        enable_fig_6
        enable_fig_7
        enable_fig_8


        data_array_1 % data array for plot
        data_array_2
        data_array_3
        data_array_4
        data_array_5
        data_array_6
        data_array_7
        data_array_8
        
        timetable % timetable data
        event_time % save next event time
        event_counter % save sequence number of event
        timetablefile % file path
        
        
        enable_auto_control = 1% enable value for auto control
        
        
        start_enable_t = 0 % start button enable log
        start_enable_s = 0 % start button enable log
        manual_status = 0 %
        
        
        comp_data_array_1 % for ploting total curve
        comp_data_array_2
        comp_data_array_3
        comp_data_array_4
        comp_data_array_5
        comp_data_array_6
        comp_data_array_7
        comp_data_array_8
        
        
        baseline_1 % baseline for each channel
        baseline_2
        baseline_3
        baseline_4
        baseline_5
        baseline_6
        baseline_7
        baseline_8
        
        time_chamber % time chamber for total ploting
        
        
        serial_num % number of serial connection
        
        
        comp_data_array_1_show % for ploting total curve
        comp_data_array_2_show
        comp_data_array_3_show
        comp_data_array_4_show
        comp_data_array_5_show
        comp_data_array_6_show
        comp_data_array_7_show
        comp_data_array_8_show
    end
    
    methods (Access = private)
        
        
        function callbacksend(app,scr,event)
            
            %add 1 second to the time counter
            
            app.time = app.time + 1;
            
            
            % receive the data and save in a txt file with time marks
            
            app.save = fgets(app.obj);


            %break the loop and process the plot procedure
            app.b = 0;
            
            
        end
        
    end
    
    methods (Access = public)


    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % read serial ports and send back to dropdown box


            delete(instrfindall);
            info = instrhwinfo('serial');
            app.serial_num = size(info.SerialPorts,1);
            for i = 1 : app.serial_num;
                dropdown_m(i) = info.SerialPorts(i,1);
            end
            app.SerialPortDropDown.Items = dropdown_m;
            
            
            app.comp_data_array_1_show = 0;
            app.comp_data_array_2_show = 0;
            app.comp_data_array_3_show = 0;
            app.comp_data_array_4_show = 0;
            app.comp_data_array_5_show = 0;
            app.comp_data_array_6_show = 0;
            app.comp_data_array_7_show = 0;
            app.comp_data_array_8_show = 0;
            
        end

        % Button pushed function: AutoButton
        function Auto_control(app, event)
            
            %enable auto control and change status
            app.enable_auto_control = 1;
            app.Lamp.Color = 'g';
            app.Lamp_2.Color = 'r';
            
            %disable the function of manual panel
            app.SendButton.Enable = 'off';
            app.CH1TextArea.Editable = 'off';
            app.CH2TextArea.Editable = 'off';
            app.CH3TextArea.Editable = 'off';
            app.CH4TextArea.Editable = 'off';
            app.CH5TextArea.Editable = 'off';
            app.CH6TextArea.Editable = 'off';
            app.CH7TextArea.Editable = 'off';
            app.CH8TextArea.Editable = 'off';
            
            
            %enable the timetable browser
            app.BrowseButton_2.Enable = 'on';
            
        end

        % Button pushed function: BrowseButton_2
        function Browse2(app, event)
            
            %get timetable
            [FileName,FilePath] = uigetfile('*.xlsx','Select the TimeTable File');
            app.timetablefile = fullfile(FilePath,FileName);
            
            app.TextArea_2.Value = app.timetablefile;
            
            app.timetable = xlsread(app.timetablefile);
            
            %set the event counter
            
            app.event_counter = 1;
            
            
            %show and set initial duty circle
            app.obj = serial(app.SerialPortDropDown.Value,'BaudRate',115200);
            fopen(app.obj);
            fprintf(app.obj,'pwm');
            
            app.CH1TextArea.Value = num2str(app.timetable(app.event_counter,2));
            fwrite(app.obj,app.CH1TextArea.Value{1});
            fwrite(app.obj,' ');
            
            app.CH2TextArea.Value = num2str(app.timetable(app.event_counter,3));
            fwrite(app.obj,num2str(app.timetable(app.event_counter,3)));
            fwrite(app.obj,' ');
            
            app.CH3TextArea.Value = num2str(app.timetable(app.event_counter,4));
            fwrite(app.obj,num2str(app.timetable(app.event_counter,4)));
            fwrite(app.obj,' ');
            
            app.CH4TextArea.Value = num2str(app.timetable(app.event_counter,5));
            fwrite(app.obj,num2str(app.timetable(app.event_counter,5)));
            fwrite(app.obj,' ');
            
            app.CH5TextArea.Value = num2str(app.timetable(app.event_counter,6));
            fwrite(app.obj,num2str(app.timetable(app.event_counter,6)));
            fwrite(app.obj,' ');
            
            app.CH6TextArea.Value = num2str(app.timetable(app.event_counter,7));
            fwrite(app.obj,num2str(app.timetable(app.event_counter,7)));
            fwrite(app.obj,' ');
            
            app.CH7TextArea.Value = num2str(app.timetable(app.event_counter,8));
            fwrite(app.obj,num2str(app.timetable(app.event_counter,8)));
            fwrite(app.obj,' ');
            
            app.CH8TextArea.Value = num2str(app.timetable(app.event_counter,9));
            fprintf(app.obj,num2str(app.timetable(app.event_counter,9)));
            
            % count 1 on counter and set next event time
            
            app.event_counter = app.event_counter + 1;
            app.event_time = app.timetable(app.event_counter,1);
            
            fclose('all');
            delete(instrfind);
            
            
            % change enable_t status and check if both enable values are 1
            
            app.start_enable_t = 1;
            
            if (app.start_enable_t + app.start_enable_s) == 2
                app.StartButton.Enable = 'on';
            end


        end

        % Button pushed function: ManualButton
        function Manual_control(app, event)
            
            
            %disable auto control
            app.enable_auto_control = 0;
            app.Lamp.Color = 'r';
            app.Lamp_2.Color = 'g';
            
            %enable manual control panel
            app.SendButton.Enable = 'on';
            app.CH1TextArea.Editable = 'on';
            app.CH2TextArea.Editable = 'on';
            app.CH3TextArea.Editable = 'on';
            app.CH4TextArea.Editable = 'on';
            app.CH5TextArea.Editable = 'on';
            app.CH6TextArea.Editable = 'on';
            app.CH7TextArea.Editable = 'on';
            app.CH8TextArea.Editable = 'on';
            app.manual_status = 1;
            app.BrowseButton_2.Enable ='off';
            
            
            %clear all auto control data
            app.TextArea_2.Value = '';
            app.CH1TextArea.Value = '0';
            app.CH2TextArea.Value = '0';
            app.CH3TextArea.Value = '0';
            app.CH4TextArea.Value = '0';
            app.CH5TextArea.Value = '0';
            app.CH6TextArea.Value = '0';
            app.CH7TextArea.Value = '0';
            app.CH8TextArea.Value = '0';
            
            
            %change value of start_enable_t and check the avaliability of start
            app.start_enable_t = 1;
            
            if (app.start_enable_t + app.start_enable_s) == 2
                app.StartButton.Enable = 'on';
            end
            
        end

        % Button pushed function: BrowseButton
        function browse(app, event)
            
            %set file path and show it
            [FileName,FilePath] = uiputfile('*.txt','Save file name');
            app.f = fullfile(FilePath,FileName);
            fopen(app.f);
            fclose('all');
            app.TextArea.Value = app.f;
            
            %change value of start_enable_s and check the avaliability of start
            app.start_enable_s = 1;
            
            if (app.start_enable_t + app.start_enable_s) == 2
                app.StartButton.Enable = 'on';
            end
            
            
        end

        % Button pushed function: ClearButton
        function clear(app, event)
            
            %disable clear button
            
            app.ClearButton.Enable = 'off'
            
            % ---------for basic function---------
            
            % clear the serial information
            fclose('all');
            delete(instrfind);
            
            % open serial port and clear the pwm wave
            app.obj = serial(app.SerialPortDropDown.Value,'BaudRate',115200);
            fopen(app.obj);
            fprintf(app.obj,'pwm');
            fprintf(app.obj,'0 0 0 0 0 0 0 0 0');
            
            %close files and delete serial information(COM terminator etc.)
            fclose('all');
            delete(instrfind);
            
            
            %clear data and file path
            app.save = 0;
            app.time = 0;
            app.f = 0;
            app.timetablefile = 0;
            app.pwm_data = 0;
            app.TextArea.Value = '';
            app.TextArea_2.Value = '';
            app.TimeTextArea.Value = '';
            app.DataTextArea.Value = '';
            
            % --------for plot figures--------
            
            %clear enable values
            app.enable_fig_1 = 0;
            app.enable_fig_2 = 0;
            app.enable_fig_3 = 0;
            app.enable_fig_4 = 0;
            app.enable_fig_5 = 0;
            app.enable_fig_6 = 0;
            app.enable_fig_7 = 0;
            app.enable_fig_8 = 0;
            
            %clear data
            app.data_array_1 = 0;
            app.data_array_2 = 0;
            app.data_array_3 = 0;
            app.data_array_4 = 0;
            app.data_array_5 = 0;
            app.data_array_6 = 0;
            app.data_array_7 = 0;
            app.data_array_8 = 0;
            
            %reset switch position
            app.Switch_1.Value = 'Off';
            app.Switch_2.Value = 'Off';
            app.Switch_3.Value = 'Off';
            app.Switch_4.Value = 'Off';
            app.Switch_5.Value = 'Off';
            app.Switch_6.Value = 'Off';
            app.Switch_7.Value = 'Off';
            app.Switch_8.Value = 'Off';
            
            %clear all text area
            
            app.CH1TextArea.Value = '';
            app.CH2TextArea.Value = '';
            app.CH3TextArea.Value = '';
            app.CH4TextArea.Value = '';
            app.CH5TextArea.Value = '';
            app.CH6TextArea.Value = '';
            app.CH7TextArea.Value = '';
            app.CH8TextArea.Value = '';
            
            %clear all auto controller data
            app.timetable = 0;
            app.event_time = 0;
            app.event_counter = 0;
            
            
            %default auto controll mode
            app.enable_auto_control = 1;
            app.Lamp.Color = 'g';
            app.Lamp_2.Color = 'r';
            app.SendButton.Enable = 'off';
            app.CH1TextArea.Editable = 'off';
            app.CH2TextArea.Editable = 'off';
            app.CH3TextArea.Editable = 'off';
            app.CH4TextArea.Editable = 'off';
            app.CH5TextArea.Editable = 'off';
            app.CH6TextArea.Editable = 'off';
            app.CH7TextArea.Editable = 'off';
            app.CH8TextArea.Editable = 'off';
            
            
            % clear enable values
            app.start_enable_s = 0;
            app.start_enable_t = 0;
            
            %enable browser and auto/manual selector
            app.BrowseButton.Enable = 'on';
            app.BrowseButton_2.Enable = 'on';
            app.AutoButton.Enable = 'on';
            app.ManualButton.Enable = 'on';
            
            
            %change status
            
            app.Lamp_6.Color = 'r';
            app.Lamp_4.Color = 'g';
            
            cla(app.UIAxes_1);
            cla(app.UIAxes_2);
            cla(app.UIAxes_3);
            cla(app.UIAxes_4);
            cla(app.UIAxes_5);
            cla(app.UIAxes_6);
            cla(app.UIAxes_7);
            cla(app.UIAxes_8);
            cla(app.UIAxes_9);
            
            app.comp_data_array_1 = 0;
            app.comp_data_array_2 = 0;
            app.comp_data_array_3 = 0;
            app.comp_data_array_4 = 0;
            app.comp_data_array_5 = 0;
            app.comp_data_array_6 = 0;
            app.comp_data_array_7 = 0;
            app.comp_data_array_8 = 0;
            app.time_chamber = 0;


        end

        % Button pushed function: ConnectButton
        function connect_serial(app, event)
            
            % clear the serial port
            delete(instrfind);
            fclose('all');
            
            % open the serial port
            app.obj = serial(app.SerialPortDropDown.Value,'BaudRate',115200);
            fopen(app.obj);
            
            %change the color
            
            app.Lamp_3.Color = 'g';
            
            %enable the browser
            app.BrowseButton.Enable = 'on';
            
            if app.manual_status == 0
                app.BrowseButton_2.Enable = 'on';
            end
            
            delete(instrfind);
            fclose('all');
            
            
        end

        % Value changed function: Switch_1
        function enable_ch1(app, event)
            
            
            value = app.Switch_1.Value;
            if value[1] == 'On'
                app.enable_fig_1 = 1;
            elseif value[1] == 'Off'
                app.enable_fig_1 = 0;
            end
            
            
        end

        % Value changed function: Switch_2
        function enable_ch2(app, event)
            
            
            value = app.Switch_2.Value;
            if value[1] == 'On'
                app.enable_fig_2 = 1;
            elseif value[1] == 'Off'
                app.enable_fig_2 = 0;
            end
            
            
        end

        % Value changed function: Switch_3
        function enable_ch3(app, event)
            
            
            value = app.Switch_3.Value;
            if value[1] == 'On'
                app.enable_fig_3 = 1;
            elseif value[1] == 'Off'
                app.enable_fig_3 = 0;
            end
            
            
        end

        % Value changed function: Switch_4
        function enable_ch4(app, event)
            
            value = app.Switch_4.Value;
            if value[1] == 'On'
                app.enable_fig_4 = 1;
            elseif value[1] == 'Off'
                app.enable_fig_4 = 0;
            end
            
        end

        % Value changed function: Switch_5
        function enable_ch5(app, event)
            
            
            value = app.Switch_5.Value;
            if value[1] == 'On'
                app.enable_fig_5 = 1;
            elseif value[1] == 'Off'
                app.enable_fig_5 = 0;
            end
            
            
        end

        % Value changed function: Switch_6
        function enable_ch6(app, event)
            
            
            value = app.Switch_6.Value;
            if value[1] == 'On'
                app.enable_fig_6 = 1;
            elseif value[1] == 'Off'
                app.enable_fig_6 = 0;
            end
            
            
        end

        % Value changed function: Switch_7
        function enable_ch7(app, event)
            
            value = app.Switch_7.Value;
            if value[1] == 'On'
                app.enable_fig_7 = 1;
            elseif value[1] == 'Off'
                app.enable_fig_7 = 0;
            end
            
        end

        % Value changed function: Switch_8
        function enable_ch8(app, event)
            
            
            value = app.Switch_8.Value;
            if value[1] == 'On'
                app.enable_fig_8 = 1;
            elseif value[1] == 'Off'
                app.enable_fig_8 = 0;
            end
            
            
        end

        % Button pushed function: SendButton
        function send(app, event)
            
            %give pwm write command
            fprintf(app.obj,'pwm');
            
            %read and send values for ch1-ch7
            for i = 1:8
                
                switch i
                    case 1
                        k = app.CH1TextArea.Value
                    case 2
                        k = app.CH2TextArea.Value
                    case 3
                        k = app.CH3TextArea.Value
                    case 4
                        k = app.CH4TextArea.Value
                    case 5
                        k = app.CH5TextArea.Value
                    case 6
                        k = app.CH6TextArea.Value
                    case 7
                        k = app.CH7TextArea.Value
                    case 8
                        k = app.CH8TextArea.Value
                        
                end
                k = k{1};
                fwrite(app.obj,k);
                fwrite(app.obj,' ');
            end
            
            %read and send value for ch8 with a terminator
            k = app.CH8TextArea.Value
            k = k{1};
            fprintf(app.obj,k);
            
            
        end

        % Button pushed function: StartButton
        function start(app, event)
            
            %enable stop button and disable start button/browse button
            
            app.BrowseButton.Enable = 'off';
            app.BrowseButton_2.Enable = 'off';
            app.StartButton.Enable = 'off';
            
            app.AutoButton.Enable = 'off';
            app.ManualButton.Enable = 'off';
            app.StopButton.Enable = 'on';
            
            %change status
            
            app.Lamp_4.Color = 'r';
            app.Lamp_5.Color = 'g';
            
            
            % initialize the break signal and time counter
            app.b = 1;
            app.a = 1;
            app.time = 0;
            app.time_chamber = 0;


            % initialize the serial port interrupt monitor
            app.obj = serial(app.SerialPortDropDown.Value,'BaudRate',115200);
            app.obj.BytesAvailableFcnMode = 'terminator';
            app.obj.Terminator = 'LF';
            app.obj.BytesAvailableFcn = {@app.callbacksend};
            fopen(app.obj);
            
            % open txt file and set the property
            app.fid = fopen(app.f,'w');
            
            %start processing and wait for stop signals
            while(app.a)
                
                while(app.b)
                    % waiting for interrupt signal
                    pause(0.001);
                end
                
                % show original data in a textarea
                app.DataTextArea.Value = app.save;
                
                % save the data in the txt file
                received_data = str2num(app.save);
                X = app.time;
                
                Tem = received_data(1,10) * 0.01 - 39.6;
                Hum_so = received_data(1,9);
                Hum = -2.0468 + 0.0367 * Hum_so - (1.5955/1000000) * (Hum_so)^2;
                Hum = (Tem - 25) * (0.01 + 0.00008 * Hum_so) + Hum;
                Hum = Hum - mod(Hum,0.01);
                
                received_data(1,10) = Tem;
                received_data(1,9) = Hum;
                
                
                fprintf(app.fid,'%d ',X);
                fprintf(app.fid,'%d ',received_data);
                fprintf(app.fid,'\r\n');
                
                %save data for UIAxes
                
                app.data_array_1(X) = received_data(1,1);
                app.data_array_2(X) = received_data(1,2);
                app.data_array_3(X) = received_data(1,3);
                app.data_array_4(X) = received_data(1,4);
                app.data_array_5(X) = received_data(1,5);
                app.data_array_6(X) = received_data(1,6);
                app.data_array_7(X) = received_data(1,7);
                app.data_array_8(X) = received_data(1,8);
                
                
                if X == 1
                    app.baseline_1 = app.data_array_1(1);
                    app.baseline_2 = app.data_array_2(1);
                    app.baseline_3 = app.data_array_3(1);
                    app.baseline_4 = app.data_array_4(1);
                    app.baseline_5 = app.data_array_5(1);
                    app.baseline_6 = app.data_array_6(1);
                    app.baseline_7 = app.data_array_7(1);
                    app.baseline_8 = app.data_array_8(1);
                end
                
                app.comp_data_array_1(X) = app.data_array_1(X) - app.baseline_1;
                app.comp_data_array_2(X) = app.data_array_2(X) - app.baseline_2;
                app.comp_data_array_3(X) = app.data_array_3(X) - app.baseline_3;
                app.comp_data_array_4(X) = app.data_array_4(X) - app.baseline_4;
                app.comp_data_array_5(X) = app.data_array_5(X) - app.baseline_5;
                app.comp_data_array_6(X) = app.data_array_6(X) - app.baseline_6;
                app.comp_data_array_7(X) = app.data_array_7(X) - app.baseline_7;
                app.comp_data_array_8(X) = app.data_array_8(X) - app.baseline_8;
                
                app.time_chamber(X) = X;
                
                for i = 1:8
                    
                    switch i
                        case 1
                            if app.enable_fig_1 == 1
                                plot(app.UIAxes_1,app.data_array_1);
                                app.comp_data_array_1_show = app.comp_data_array_1;
                            elseif app.enable_fig_1 == 0
                                app.comp_data_array_1_show = app.comp_data_array_1 * 0;
                            end
                        case 2
                            if app.enable_fig_2 == 1
                                plot(app.UIAxes_2,app.data_array_2);
                                app.comp_data_array_2_show = app.comp_data_array_2;
                            else
                                app.comp_data_array_2_show = app.comp_data_array_2 * 0;
                            end
                        case 3
                            if app.enable_fig_3 == 1
                                plot(app.UIAxes_3,app.data_array_3);
                                app.comp_data_array_3_show = app.comp_data_array_3;
                            else
                                app.comp_data_array_3_show = app.comp_data_array_3 * 0;
                            end
                        case 4
                            if app.enable_fig_4 == 1
                                plot(app.UIAxes_4,app.data_array_4);
                                app.comp_data_array_4_show = app.comp_data_array_4;
                            else
                                app.comp_data_array_4_show = app.comp_data_array_4 * 0;
                            end
                        case 5
                            if app.enable_fig_5 == 1
                                plot(app.UIAxes_5,app.data_array_5);
                                app.comp_data_array_5_show = app.comp_data_array_5;
                            else
                                app.comp_data_array_5_show = app.comp_data_array_5 * 0;
                            end
                        case 6
                            if app.enable_fig_6 == 1
                                plot(app.UIAxes_6,app.data_array_6);
                                app.comp_data_array_6_show = app.comp_data_array_6;
                            else
                                app.comp_data_array_6_show = app.comp_data_array_6 * 0;
                            end
                        case 7
                            if app.enable_fig_7 == 1
                                plot(app.UIAxes_7,app.data_array_7);
                                app.comp_data_array_7_show = app.comp_data_array_7;
                            else
                                app.comp_data_array_7_show = app.comp_data_array_7 * 0;
                            end
                        case 8
                            if app.enable_fig_8 == 1
                                plot(app.UIAxes_8,app.data_array_8);
                                app.comp_data_array_8_show = app.comp_data_array_8;
                            else
                                app.comp_data_array_8_show = app.comp_data_array_8 * 0;
                            end
                    end
                    
                end
                
                app.EditField.Value = num2str(Hum);
                app.EditField_2.Value = num2str(Tem);
                plot(app.UIAxes_9,app.time_chamber,app.comp_data_array_1_show,'y',app.time_chamber,app.comp_data_array_2_show,'m',app.time_chamber,app.comp_data_array_3_show,'c',app.time_chamber,app.comp_data_array_4_show,'r',app.time_chamber,app.comp_data_array_5_show,'g',app.time_chamber,app.comp_data_array_6_show,'b',app.time_chamber,app.comp_data_array_7_show,'b--',app.time_chamber,app.comp_data_array_8_show,'k');
                legend(app.UIAxes_9,'CH1','CH2','CH3','CH4','CH5','CH6','CH7','CH8');
                
                % if event time has come, process auto controller of pwm
                if app.enable_auto_control == 1
                    if X == app.event_time
                        
                        
                        %send command
                        
                        fprintf(app.obj,'pwm');
                        
                        app.CH1TextArea.Value = num2str(app.timetable(app.event_counter,2));
                        fwrite(app.obj,app.CH1TextArea.Value{1});
                        fwrite(app.obj,' ');
                        
                        app.CH2TextArea.Value = num2str(app.timetable(app.event_counter,3));
                        fwrite(app.obj,num2str(app.timetable(app.event_counter,3)));
                        fwrite(app.obj,' ');
                        
                        app.CH3TextArea.Value = num2str(app.timetable(app.event_counter,4));
                        fwrite(app.obj,num2str(app.timetable(app.event_counter,4)));
                        fwrite(app.obj,' ');
                        
                        app.CH4TextArea.Value = num2str(app.timetable(app.event_counter,5));
                        fwrite(app.obj,num2str(app.timetable(app.event_counter,5)));
                        fwrite(app.obj,' ');
                        
                        app.CH5TextArea.Value = num2str(app.timetable(app.event_counter,6));
                        fwrite(app.obj,num2str(app.timetable(app.event_counter,6)));
                        fwrite(app.obj,' ');
                        
                        app.CH6TextArea.Value = num2str(app.timetable(app.event_counter,7));
                        fwrite(app.obj,num2str(app.timetable(app.event_counter,7)));
                        fwrite(app.obj,' ');
                        
                        app.CH7TextArea.Value = num2str(app.timetable(app.event_counter,8));
                        fwrite(app.obj,num2str(app.timetable(app.event_counter,8)));
                        fwrite(app.obj,' ');
                        
                        app.CH8TextArea.Value = num2str(app.timetable(app.event_counter,9));
                        fprintf(app.obj,num2str(app.timetable(app.event_counter,9)));
                        
                        % read next event time and add 1 to event counter
                        
                        app.event_counter = app.event_counter + 1;
                        app.event_time = app.timetable(app.event_counter,1);


                    end
                    
                    
                    if app.event_time == 0
                        app.a = 0;
                    end
                    
                end
                show_time = num2str(X);
                app.TimeTextArea.Value = show_time;
                
                app.b = 1;


            end
            
            
            %ensure that all channel closed
            fprintf(app.obj,'pwm');
            fprintf(app.obj,'0 0 0 0 0 0 0 0 0');
            
            
            %close all
            fclose('all');
            delete(instrfind);
            
            %enable clear button and disable stop
            app.StopButton.Enable = 'off';
            app.ClearButton.Enable = 'on';
            
            
            %change status
            
            app.Lamp_5.Color = 'r';
            app.Lamp_6.Color = 'g';
            
        end

        % Button pushed function: StopButton
        function stop(app, event)
            
            %break loop and enable stop button
            app.a = 0;
            app.StopButton.Enable = 'off';
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.FileOperationPanel = uipanel(app.UIFigure);
            app.FileOperationPanel.Title = 'File Operation';
            app.BrowseButton = uibutton(app.FileOperationPanel, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @browse, true);
            app.BrowseButton.Enable = 'off';
            app.BrowseButton.Text = 'Browse';
            app.TextArea = uitextarea(app.FileOperationPanel);
            app.TextArea.Editable = 'off';
            app.BrowseButton_2 = uibutton(app.FileOperationPanel, 'push');
            app.BrowseButton_2.ButtonPushedFcn = createCallbackFcn(app, @Browse2, true);
            app.BrowseButton_2.Enable = 'off';
            app.BrowseButton_2.Text = 'Browse';
            app.TextArea_2 = uitextarea(app.FileOperationPanel);
            app.TextArea_2.Editable = 'off';
            app.FileSaveLabel = uilabel(app.FileOperationPanel);
            app.FileSaveLabel.Text = 'File Save:';
            app.TimeTableLabel = uilabel(app.FileOperationPanel);
            app.TimeTableLabel.Text = 'Time Table:';
            app.SerialDataMonitorPanel = uipanel(app.UIFigure);
            app.SerialDataMonitorPanel.Title = 'Serial Data Monitor';
            app.DataTextAreaLabel = uilabel(app.SerialDataMonitorPanel);
            app.DataTextAreaLabel.Text = 'Data';
            app.DataTextArea = uitextarea(app.SerialDataMonitorPanel);
            app.DataTextArea.Editable = 'off';
            app.ControlButtonPanel = uipanel(app.UIFigure);
            app.ControlButtonPanel.Title = 'Control Button';
            app.StartButton = uibutton(app.ControlButtonPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @start, true);
            app.StartButton.Enable = 'off';
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.ControlButtonPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @stop, true);
            app.StopButton.Enable = 'off';
            app.StopButton.Text = 'Stop';
            app.ClearButton = uibutton(app.ControlButtonPanel, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @clear, true);
            app.ClearButton.Enable = 'off';
            app.ClearButton.Text = 'Clear';
            app.ValveControllerPanel = uipanel(app.UIFigure);
            app.ValveControllerPanel.Title = 'Valve Controller';
            app.SendButton = uibutton(app.ValveControllerPanel, 'push');
            app.SendButton.ButtonPushedFcn = createCallbackFcn(app, @send, true);
            app.SendButton.Enable = 'off';
            app.SendButton.Text = 'Send';
            app.CH1TextAreaLabel = uilabel(app.ValveControllerPanel);
            app.CH1TextAreaLabel.Text = 'CH1';
            app.CH1TextArea = uitextarea(app.ValveControllerPanel);
            app.CH1TextArea.Editable = 'off';
            app.CH2TextAreaLabel = uilabel(app.ValveControllerPanel);
            app.CH2TextAreaLabel.Text = 'CH2';
            app.CH2TextArea = uitextarea(app.ValveControllerPanel);
            app.CH2TextArea.Editable = 'off';
            app.CH3TextAreaLabel = uilabel(app.ValveControllerPanel);
            app.CH3TextAreaLabel.Text = 'CH3';
            app.CH3TextArea = uitextarea(app.ValveControllerPanel);
            app.CH3TextArea.Editable = 'off';
            app.CH4TextAreaLabel = uilabel(app.ValveControllerPanel);
            app.CH4TextAreaLabel.Text = 'CH4';
            app.CH4TextArea = uitextarea(app.ValveControllerPanel);
            app.CH4TextArea.Editable = 'off';
            app.CH5TextAreaLabel = uilabel(app.ValveControllerPanel);
            app.CH5TextAreaLabel.Text = 'CH5';
            app.CH5TextArea = uitextarea(app.ValveControllerPanel);
            app.CH5TextArea.Editable = 'off';
            app.CH6TextAreaLabel = uilabel(app.ValveControllerPanel);
            app.CH6TextAreaLabel.Text = 'CH6';
            app.CH6TextArea = uitextarea(app.ValveControllerPanel);
            app.CH6TextArea.Editable = 'off';
            app.CH7TextAreaLabel = uilabel(app.ValveControllerPanel);
            app.CH7TextAreaLabel.Text = 'CH7';
            app.CH7TextArea = uitextarea(app.ValveControllerPanel);
            app.CH7TextArea.Editable = 'off';
            app.CH8TextAreaLabel = uilabel(app.ValveControllerPanel);
            app.CH8TextAreaLabel.Text = 'CH8';
            app.CH8TextArea = uitextarea(app.ValveControllerPanel);
            app.CH8TextArea.Editable = 'off';
            app.CurveMonitorPanel = uipanel(app.UIFigure);
            app.CurveMonitorPanel.Title = 'Curve Monitor';
            app.TabGroup = uitabgroup(app.CurveMonitorPanel);
            app.Tab1 = uitab(app.TabGroup);
            app.Tab1.Title = 'CH1';
            app.UIAxes_1 = uiaxes(app.Tab1);
            title(app.UIAxes_1, 'Channel 1')
            xlabel(app.UIAxes_1, 'Time(s)')
            ylabel(app.UIAxes_1, 'Frequency(Hz)')
            app.Switch_1 = uiswitch(app.Tab1, 'slider');
            app.Switch_1.ValueChangedFcn = createCallbackFcn(app, @enable_ch1, true);
            app.CH2Tab = uitab(app.TabGroup);
            app.CH2Tab.Title = 'CH2';
            app.UIAxes_2 = uiaxes(app.CH2Tab);
            title(app.UIAxes_2, 'Channel 2')
            xlabel(app.UIAxes_2, 'Time(s)')
            ylabel(app.UIAxes_2, 'Frequency(Hz)')
            app.Switch_2 = uiswitch(app.CH2Tab, 'slider');
            app.Switch_2.ValueChangedFcn = createCallbackFcn(app, @enable_ch2, true);
            app.CH3Tab = uitab(app.TabGroup);
            app.CH3Tab.Title = 'CH3';
            app.UIAxes_3 = uiaxes(app.CH3Tab);
            title(app.UIAxes_3, 'Channel 3')
            xlabel(app.UIAxes_3, 'Time(s)')
            ylabel(app.UIAxes_3, 'Frequency(Hz)')
            app.Switch_3 = uiswitch(app.CH3Tab, 'slider');
            app.Switch_3.ValueChangedFcn = createCallbackFcn(app, @enable_ch3, true);
            app.CH4Tab = uitab(app.TabGroup);
            app.CH4Tab.Title = 'CH4';
            app.UIAxes_4 = uiaxes(app.CH4Tab);
            title(app.UIAxes_4, 'Channel 4')
            xlabel(app.UIAxes_4, 'Time(s)')
            ylabel(app.UIAxes_4, 'Frequency(Hz)')
            app.Switch_4 = uiswitch(app.CH4Tab, 'slider');
            app.Switch_4.ValueChangedFcn = createCallbackFcn(app, @enable_ch4, true);
            app.CH5Tab = uitab(app.TabGroup);
            app.CH5Tab.Title = 'CH5';
            app.UIAxes_5 = uiaxes(app.CH5Tab);
            title(app.UIAxes_5, 'Channel 5')
            xlabel(app.UIAxes_5, 'Time(s)')
            ylabel(app.UIAxes_5, 'Frequency(Hz)')
            app.Switch_5 = uiswitch(app.CH5Tab, 'slider');
            app.Switch_5.ValueChangedFcn = createCallbackFcn(app, @enable_ch5, true);
            app.CH6Tab = uitab(app.TabGroup);
            app.CH6Tab.Title = 'CH6';
            app.Switch_6 = uiswitch(app.CH6Tab, 'slider');
            app.Switch_6.ValueChangedFcn = createCallbackFcn(app, @enable_ch6, true);
            app.UIAxes_6 = uiaxes(app.CH6Tab);
            title(app.UIAxes_6, 'Channel 6')
            xlabel(app.UIAxes_6, 'Time(s)')
            ylabel(app.UIAxes_6, 'Frequency(Hz)')
            app.CH7Tab = uitab(app.TabGroup);
            app.CH7Tab.Title = 'CH7';
            app.UIAxes_7 = uiaxes(app.CH7Tab);
            title(app.UIAxes_7, 'Channel 7')
            xlabel(app.UIAxes_7, 'Time(s)')
            ylabel(app.UIAxes_7, 'Frequency(Hz)')
            app.Switch_7 = uiswitch(app.CH7Tab, 'slider');
            app.Switch_7.ValueChangedFcn = createCallbackFcn(app, @enable_ch7, true);
            app.CH8Tab = uitab(app.TabGroup);
            app.CH8Tab.Title = 'CH8';
            app.UIAxes_8 = uiaxes(app.CH8Tab);
            title(app.UIAxes_8, 'Channel 8')
            xlabel(app.UIAxes_8, 'Time(s)')
            ylabel(app.UIAxes_8, 'Frequency(Hz)')
            app.Switch_8 = uiswitch(app.CH8Tab, 'slider');
            app.Switch_8.ValueChangedFcn = createCallbackFcn(app, @enable_ch8, true);
            app.TotalTab = uitab(app.TabGroup);
            app.TotalTab.Title = 'Total';
            app.UIAxes_9 = uiaxes(app.TotalTab);
            title(app.UIAxes_9, 'Total')
            xlabel(app.UIAxes_9, 'Time(s)')
            ylabel(app.UIAxes_9, 'Frequency(Hz)')
            app.Switch_9 = uiswitch(app.TotalTab, 'slider');
            app.TimerPanel = uipanel(app.UIFigure);
            app.TimerPanel.Title = 'Timer';
            app.TimeTextArea = uitextarea(app.TimerPanel);
            app.OptionsPanel = uipanel(app.UIFigure);
            app.OptionsPanel.Title = 'Options';
            app.AutoButton = uibutton(app.OptionsPanel, 'push');
            app.AutoButton.ButtonPushedFcn = createCallbackFcn(app, @Auto_control, true);
            app.AutoButton.Text = 'Auto';
            app.Lamp = uilamp(app.OptionsPanel);
            app.ManualButton = uibutton(app.OptionsPanel, 'push');
            app.ManualButton.ButtonPushedFcn = createCallbackFcn(app, @Manual_control, true);
            app.ManualButton.Text = 'Manual';
            app.Lamp_2 = uilamp(app.OptionsPanel);
            app.ConnectionPanel = uipanel(app.UIFigure);
            app.ConnectionPanel.Title = 'Connection';
            app.ConnectButton = uibutton(app.ConnectionPanel, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @connect_serial, true);
            app.ConnectButton.Text = 'Connect';
            app.SerialPortDropDownLabel = uilabel(app.ConnectionPanel);
            app.SerialPortDropDownLabel.Text = 'Serial Port';
            app.SerialPortDropDown = uidropdown(app.ConnectionPanel);
            app.SerialPortDropDown.Items = {};
            app.SerialPortDropDown.Value = {};
            app.Lamp_3 = uilamp(app.ConnectionPanel);
            app.StatusIndicatorPanel = uipanel(app.UIFigure);
            app.StatusIndicatorPanel.Title = 'Status Indicator';
            app.Lamp_4 = uilamp(app.StatusIndicatorPanel);
            app.Lamp_5 = uilamp(app.StatusIndicatorPanel);
            app.Lamp_6 = uilamp(app.StatusIndicatorPanel);
            app.PreparingLabel = uilabel(app.StatusIndicatorPanel);
            app.PreparingLabel.Text = 'Preparing';
            app.ProcessingLabel = uilabel(app.StatusIndicatorPanel);
            app.ProcessingLabel.Text = 'Processing';
            app.ClearingLabel = uilabel(app.StatusIndicatorPanel);
            app.ClearingLabel.Text = 'Clearing';
            app.HumidityTemperaturePanel = uipanel(app.UIFigure);
            app.HumidityTemperaturePanel.Title = 'Humidity & Temperature';
            app.EditField = uieditfield(app.HumidityTemperaturePanel, 'text');
            app.EditField.Editable = 'off';
            app.EditField_2 = uieditfield(app.HumidityTemperaturePanel, 'text');
            app.EditField_2.Editable = 'off';
            app.Label = uilabel(app.HumidityTemperaturePanel);
            app.Label.Text = '℃';
            app.Label_2 = uilabel(app.HumidityTemperaturePanel);
            app.Label_2.Text = '%';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Odor_System

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end